classdef qracle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QracleUIFigure          matlab.ui.Figure
        SAVEButton              matlab.ui.control.Button
        ImageSizeDropDown       matlab.ui.control.DropDown
        ImageSizeDropDownLabel  matlab.ui.control.Label
        GENERATEButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property
        I=[];
        name='256';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            app.SAVEButton.Enable='off';
            switch app.ImageSizeDropDown.Value
                case '32'
                    size=32;
                case '64'
                    size=64;
                case '128'
                    size=128;
                case '256'
                    size=256;
                case '512'
                    size=512;
                case '1024'
                    size=1024;
                case '2048'
                    size=2048;
            end
            app.name=num2str(size);
            roll=size^2/1024;
            rollm=roll;
            rollm(rollm>1024)=1024;
            C=zeros(rollm,1024);
            rnd=[];
            url=['https://qrng.anu.edu.au/API/jsonI.php?length=',int2str(rollm),'&type=hex16&size=1024'];
            for i=1:1:ceil(roll/1024)
                rnd=[rnd;cell2mat(webread(url).data)];
            end
            for i=1:1:1024
                C(:,i)=hex2dec(rnd(:,(2*i-1):(2*i)));
            end
            app.I=mat2gray(reshape(C,size,size));
            imshow(app.I,'Parent',app.UIAxes);
            app.SAVEButton.Enable='on';
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            [file,path] = uiputfile({'*.png','PNG (*.png)';'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.*','All Files (*.*)'},'SAVE',app.name);
            filename=fullfile(path,file);
            if filename==0
                return;
            end
            imwrite(app.I,filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QracleUIFigure = uifigure('Visible', 'off');
            app.QracleUIFigure.Name = 'Qracle';
            app.QracleUIFigure.Icon = 'Hudie_logo.png';
            app.UIAxes = uiaxes(app.QracleUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GENERATEButton = uibutton(app.QracleUIFigure, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.ImageSizeDropDownLabel = uilabel(app.QracleUIFigure);
            app.ImageSizeDropDownLabel.Text = 'Image Size';
            app.ImageSizeDropDown = uidropdown(app.QracleUIFigure);
            app.ImageSizeDropDown.Items = {'32', '64', '128', '256', '512', '1024', '2048'};
            app.ImageSizeDropDown.Value = '256';
            app.SAVEButton = uibutton(app.QracleUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.QracleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qracle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QracleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QracleUIFigure)
        end
    end
end